classdef mainGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        QuitButton              matlab.ui.control.Button
        Bet1EditFieldLabel      matlab.ui.control.Label
        Bet1EditField           matlab.ui.control.NumericEditField
        Balance1EditFieldLabel  matlab.ui.control.Label
        Balance1EditField       matlab.ui.control.NumericEditField
        Bet2EditFieldLabel      matlab.ui.control.Label
        Bet2EditField           matlab.ui.control.NumericEditField
        Balance2EditFieldLabel  matlab.ui.control.Label
        Balance2EditField       matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        Button                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % Description
        img
        testA = 10;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startApp, numPlayers, bet1, balance1, bet2, balance2)
            app.CallingApp = startApp;
            app.Bet1EditField.Value = bet1;
            app.Balance1EditField.Value = balance1;
            app.Bet2EditField.Value = bet2;
            app.Balance2EditField.Value = balance2;
            
            if(bet2 == 0)
                app.Bet2EditField.Visible = 'off';
                app.Bet2EditFieldLabel.Visible = 'off';
                app.Balance2EditField.Visible = 'off';
                app.Balance2EditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cardDeck(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.Text = 'Quit';
            app.Bet1EditFieldLabel = uilabel(app.UIFigure);
            app.Bet1EditFieldLabel.Text = 'Bet 1';
            app.Bet1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditFieldLabel = uilabel(app.UIFigure);
            app.Balance1EditFieldLabel.Text = 'Balance 1';
            app.Balance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditField.Value = 2000;
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditField.Value = 2000;
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGame(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end